classdef HEVApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationMenu                  matlab.ui.container.Menu
        ExportResultsMenu               matlab.ui.container.Menu
        LoadResultsMenu                 matlab.ui.container.Menu
        GenerateReportMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationonHEVP4Menu        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        GridLayout38                    matlab.ui.container.GridLayout
        HTML                            matlab.ui.control.HTML
        LoadSessionButton               matlab.ui.control.Button
        NewSessionButton                matlab.ui.control.Button
        WelcomeLabel                    matlab.ui.control.Label
        DriveCycleSetupTab              matlab.ui.container.Tab
        GridLayout39                    matlab.ui.container.GridLayout
        GridLayout41                    matlab.ui.container.GridLayout
        DriveCycleOptionsPanel          matlab.ui.container.Panel
        GridLayout46                    matlab.ui.container.GridLayout
        GridLayout56                    matlab.ui.container.GridLayout
        SourceVelocityUnitsDropDownLabel  matlab.ui.control.Label
        SourceVelocityUnitsDropDown     matlab.ui.control.DropDown
        WideOpenThrottleWOTPanel        matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        WOTTime                         matlab.ui.control.NumericEditField
        WOTTimeLabel                    matlab.ui.control.Label
        RefSpeed                        matlab.ui.control.NumericEditField
        RefSpeedLabel                   matlab.ui.control.Label
        FinSpeed                        matlab.ui.control.NumericEditField
        FinSpeedLabel                   matlab.ui.control.Label
        InSpeed                         matlab.ui.control.NumericEditField
        InSpeedLabel                    matlab.ui.control.Label
        DecTime                         matlab.ui.control.NumericEditField
        DecTimeLabel                    matlab.ui.control.Label
        StartTime                       matlab.ui.control.NumericEditField
        StartTimeLabel                  matlab.ui.control.Label
        GridLayout47                    matlab.ui.container.GridLayout
        RepeatcyclicallyCheckBox        matlab.ui.control.CheckBox
        EndSimulationTimeEditField      matlab.ui.control.NumericEditField
        EndSimulationTimesEditFieldLabel  matlab.ui.control.Label
        GridLayout30                    matlab.ui.container.GridLayout
        DriveCycleFromFileButton        matlab.ui.control.Button
        DriveCycleFromDropDown          matlab.ui.control.DropDown
        SelectdrivecycleLabel           matlab.ui.control.Label
        GridLayout40                    matlab.ui.container.GridLayout
        DriveCycleTable                 matlab.ui.control.Table
        DriveCycleAxes                  matlab.ui.control.UIAxes
        ModelParametersSetupTab         matlab.ui.container.Tab
        GridLayout50                    matlab.ui.container.GridLayout
        EnginePanel                     matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        GridLayout36                    matlab.ui.container.GridLayout
        EngineTable                     matlab.ui.control.Table
        ECMLabel                        matlab.ui.control.Label
        GridLayout35                    matlab.ui.container.GridLayout
        SelectEngineTypeDropDown        matlab.ui.control.DropDown
        SelectEngineTypeDropDownLabel   matlab.ui.control.Label
        BatteryPanel                    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        BatteryTable                    matlab.ui.control.Table
        BatteryTypeLabel                matlab.ui.control.Label
        VehicleParametersPanel          matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout39_2                  matlab.ui.container.GridLayout
        InitialBatterySOCLabel          matlab.ui.control.Label
        InitialBatterySOCSlider         matlab.ui.control.Slider
        GridLayout24                    matlab.ui.container.GridLayout
        VehicleMassLabel                matlab.ui.control.Label
        UnloadedWheelRadiusLabel        matlab.ui.control.Label
        LoadedWheelRadiusLabel          matlab.ui.control.Label
        VehicleMassEditField            matlab.ui.control.NumericEditField
        UnloadedWheelRadiusEditField    matlab.ui.control.NumericEditField
        LoadedWheelRadiusEditField      matlab.ui.control.NumericEditField
        EnvironmentParametersPanel      matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        AtmosphericPressureLabel        matlab.ui.control.Label
        AtmosphericPressureSlider       matlab.ui.control.Slider
        GridLayout14                    matlab.ui.container.GridLayout
        TemperatureLabel                matlab.ui.control.Label
        LongitudinalWindSpeedLabel      matlab.ui.control.Label
        GradeLabel                      matlab.ui.control.Label
        LongitudinalWindSpeedEditField  matlab.ui.control.NumericEditField
        TemperatureSpinner              matlab.ui.control.Spinner
        GradeEditField                  matlab.ui.control.NumericEditField
        SimulationandVisualizationTab   matlab.ui.container.Tab
        GridLayout51                    matlab.ui.container.GridLayout
        PastResultsLabel                matlab.ui.control.Label
        VisualizationDashboardPanel     matlab.ui.container.Panel
        GridLayout59                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        GridLayout58                    matlab.ui.container.GridLayout
        GridLayout57                    matlab.ui.container.GridLayout
        GridLayout53                    matlab.ui.container.GridLayout
        ClearAllButton                  matlab.ui.control.Button
        EmissionsFuelEconomyPanel       matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        FuelEconomyL100kmCheckBox       matlab.ui.control.CheckBox
        USFuelEconomyCheckBox           matlab.ui.control.CheckBox
        CO2gkmCheckBox                  matlab.ui.control.CheckBox
        NOxgmiCheckBox                  matlab.ui.control.CheckBox
        COgmiCheckBox                   matlab.ui.control.CheckBox
        HCgmiCheckBox                   matlab.ui.control.CheckBox
        BatteryPanel_2                  matlab.ui.container.Panel
        GridLayout51_2                  matlab.ui.container.GridLayout
        BatteryCurrentCheckBox          matlab.ui.control.CheckBox
        BatterySOCCheckBox              matlab.ui.control.CheckBox
        VehicleActuatorsPanel           matlab.ui.container.Panel
        GridLayout50_2                  matlab.ui.container.GridLayout
        EngineMotorSpeedCheckBoxnn      matlab.ui.control.CheckBox
        EngineMotorTorqueCheckBox       matlab.ui.control.CheckBox
        VehicleSpeedCheckBox            matlab.ui.control.CheckBox
        OutputPlotsLabel                matlab.ui.control.Label
        SimulationControlPanel          matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        SimulationLamp                  matlab.ui.control.Lamp
        SessionLogTab                   matlab.ui.container.Tab
        GridLayout54                    matlab.ui.container.GridLayout
        SessionhistoryPanel             matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        LogTextArea                     matlab.ui.control.TextArea
        SessiondetailsPanel             matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        TimeLogLabel                    matlab.ui.control.Label
        ReportTab                       matlab.ui.container.Tab
        GridLayout55                    matlab.ui.container.GridLayout
        ReportAxes                      matlab.ui.control.UIAxes
    end

    properties
        % Simulation handler. SH.TMP = Tunable Model Parameters. Needs to
        % stay public in order to be seen by AppSession
        SH
    end
    
    properties (Access = private)
        % Session handler
        Session % To save/load a session
        TileContainer
        % Plots
        DriveCycleLine
        % Results
        ProgDlg
        PlotAxes
        PlotQuantity
        % Line objects
        ResultLine (2,:) = matlab.graphics.chart.primitive.Line
        PastLine   (2,:) = matlab.graphics.chart.primitive.Line
        DiffLine   (2,:) = matlab.graphics.chart.primitive.Line
        % Menus associated with each plot
        ContextMenu (:,1) matlab.ui.container.ContextMenu
        ShowMenu    (:,1) matlab.ui.container.Menu
        HideMenu    (:,1) matlab.ui.container.Menu
        ShowResMenu (:,1) matlab.ui.container.Menu
        ShowLatMenu (:,1) matlab.ui.container.Menu
        HideResMenu (:,1) matlab.ui.container.Menu
        ShowDifMenu (:,1) matlab.ui.container.Menu
        HideDifMenu (:,1) matlab.ui.container.Menu
        HideLatMenu (:,1) matlab.ui.container.Menu
        % Custom colors
        Green    = [0.16 0.61 0]
        DarkRed  = [0.7 0.13 0.13]
        DarkBlue = [0 0 0.5]
        SkyBlue  = [0 0.73 1]
        Orange   = [1 0.5 0]
    end

    methods (Access = private)
        
        function addTMPListeners(app)
            addlistener(app.SH.TMP,"Error", @app.onError);
            addlistener(app.SH.TMP,"Units","PostSet",@(~,~) app.onUnitsChanged);
            addlistener(app.SH.TMP,"Engine","PostSet",@(~,~) app.onEngineChanged);
            addlistener(app.SH.TMP,"EndSimulationTime","PostSet",@(~,~) app.onEndSimulationTimeChanged);
            addlistener(app.SH.TMP,"InitialSOC","PostSet",@(~,~) app.onInitialSOCChanged);
            addlistener(app.SH.TMP,"DriveCycleChanged", @(~,~) app.onDriveCycleChanged);
        end

        % Add log information in the Log tab
        function addLogMessage(app, MsgType, varargin)

            % Construct headline
            DateTime = datestr(datetime);
            Date     = convertCharsToStrings(date);
            Time     = convertCharsToStrings(DateTime(13:end));
            Headline = strcat("------ On ", Date, " at ", Time, ":");
            
            % Select message to display
            switch MsgType
                case 'SimulationStarted'
                    Msg = ["Simulation started with the following configuration:";
                        "";
                        "End Simulation time (s): " + app.SH.TMP.EndSimulationTime;
                        "";
                        "Drive cycle parameters: ";
                        "Drive cycle source:    " + app.SH.TMP.DriveCycleType;
                        "Source Velocity Units: " + app.SH.TMP.Units];
                    if strcmp(app.SH.TMP.DriveCycleType,'Wide Open Throttle (WOT)')
                        Msg = [Msg;
                            app.StartTimeLabel.Text + "                 " + app.SH.TMP.StartTime;
                            app.InSpeedLabel.Text + "  " + app.SH.TMP.InSpeed;
                            app.RefSpeedLabel.Text + "  " + app.SH.TMP.RefSpeed;
                            app.DecTimeLabel.Text + " " + app.SH.TMP.DecTime;
                            app.FinSpeedLabel.Text + "    " + app.SH.TMP.FinSpeed;
                            app.WOTTimeLabel.Text + "       " + app.SH.TMP.WOTTime];
                    end
                    Msg = [Msg;
                        "";
                        %
                        "Environment parameters:";
                        "Wind Speed (m/s):           " + app.SH.TMP.WindSpeed;
                        "Grade (deg):                " + app.SH.TMP.Grade;
                        "Temperature (K):            " + app.SH.TMP.Temperature;
                        "Atmospheric Pressure (atm): " + app.SH.TMP.Pressure;
                        "";
                        %
                        "Vehicle parameters:";
                        "Mass (kg):                 " + app.SH.TMP.VehicleMass;
                        "Unloaded wheel radius (m): " + app.SH.TMP.UnloadedRadius;
                        "Loaded wheel radius (m):   " + app.SH.TMP.LoadedRadius;
                        "Initial Battery SOC (%):   " + app.SH.TMP.InitialSOC;
                        "Engine Type:               " + app.SH.TMP.Engine];
                case 'SimulationCompleted'
                    Msg = "Simulation terminated.";
                case 'NewSession'
                    Msg = "New session started.";
                case 'SavedSession'
                    Msg = "Session saved successfully.";
                case 'ReportGenerated'
                    Msg = "Simulation report created successfully.";
                case 'ReportFailed'
                    Msg = "Failed attempt to create simulation report.";
                case 'SavedAsSession'
                    Msg = "Session saved at: " + convertCharsToStrings(varargin{1});
                case 'LoadedSession'
                    Msg = "Session loaded from: " + convertCharsToStrings(varargin{1});
                case 'ResultsExported'
                    Msg = "Simulation results exported to: " + convertCharsToStrings(varargin{1});
                case 'LoadedResults'
                    Msg = "Past results loaded from: " + convertCharsToStrings(varargin{1});
                case 'SimulationPaused'
                    Msg = "Simulation paused.";
            end
            if strcmp(app.LogTextArea.Value, "")
                % If log text area is empty
                app.LogTextArea.Value = [Headline; Msg; ""];
            else
                app.LogTextArea.Value = [app.LogTextArea.Value; Headline; Msg; ""];
            end
            %scroll(app.LogTextArea, "bottom")
        end

        function clearAllPlotChecks(app)
            % Uncheck all plots
            app.CO2gkmCheckBox.Value             = false;
            app.COgmiCheckBox.Value              = false;
            app.HCgmiCheckBox.Value              = false;
            app.NOxgmiCheckBox.Value             = false;
            app.USFuelEconomyCheckBox.Value      = false;
            app.BatterySOCCheckBox.Value         = false;
            app.BatteryCurrentCheckBox.Value     = false;
            app.EngineMotorTorqueCheckBox.Value  = false;
            app.EngineMotorSpeedCheckBoxnn.Value = false;
            app.VehicleSpeedCheckBox.Value       = false;
            app.FuelEconomyL100kmCheckBox.Value  = false;
            
            app.PlotQuantity(:) = false;

            % Update number of active simulation plots
            for i = 1:app.SH.NOutputs
                app.ShowLatMenu(i).Checked = 'off';
                app.ShowResMenu(i).Checked = 'off';
                app.ShowDifMenu(i).Checked = 'off';
                app.ShowResMenu(i).Checked = 'off';
                app.HideResMenu(i).Checked = 'off';
                app.HideDifMenu(i).Checked = 'off';
            end

            % Delete plots
            delete(app.PlotAxes)
        end

        % Create line objects for tiles in Simulation and Visualization tab
        function createTileLines(app,i,Res)

            % Distinguish between quantities with two lines (e.g., velocity) and one
            % line (like emissions)
            % Labels for results plots
            PlotLabels1 = {'Target','Engine', 'Engine'};
            PlotLabels2 = {'Actual','Motor' , 'Motor' };
            
            switch i
                case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                        app.SH.Map("EngSpd, MotSpd (RPM)")
                        app.SH.Map("EngTrq, MotTrq (Nm)")}
                    % Latest results
                    switch Res
                       case 'New'
                           app.ResultLine(1:2,i) = plot(app.PlotAxes(i), [NaN;NaN], [NaN NaN; NaN NaN], Linewidth=1.5);
                        case 'Old'
                            app.ResultLine(1:2,i) = plot(app.PlotAxes(i),app.SH.Results.Time{i},...
                                app.SH.Results.Data{i}, Linewidth=1.5);
                            
                            app.ShowLatMenu(i).Checked = 'on';
                            app.HideLatMenu(i).Checked = 'on';
                    end
                    app.ResultLine(1,i).DisplayName = [PlotLabels1{i} ' (latest)'];
                    app.ResultLine(2,i).DisplayName = [PlotLabels2{i} ' (latest)'];
                    % Past results
                    app.PastLine(1,i) = plot(app.PlotAxes(i), NaN, NaN,...
                        Color=app.DarkRed, Linewidth=1.5, HandleVisibility='off',...
                        DisplayName=[PlotLabels1{i} ' (past)']);
                    app.PastLine(2,i) = plot(app.PlotAxes(i), NaN, NaN,...
                        Color=app.DarkBlue, Linewidth=1.5, HandleVisibility='off',...
                        DisplayName = [PlotLabels2{i} ' (past)']);
                    % Difference in results
                    app.DiffLine(1,i) = plot(app.PlotAxes(i), NaN, NaN,...
                        Color=app.Orange, Linestyle=':', Linewidth=1.5,...
                        HandleVisibility='off', DisplayName=[PlotLabels1{i} ' (difference)']);
                    app.DiffLine(2,i) = plot(app.PlotAxes(i), NaN, NaN,...
                        Color=app.SkyBlue, Linestyle=':', Linewidth=1.5,...
                        HandleVisibility='off', DisplayName=[PlotLabels2{i} ' (difference)']);
                otherwise
                    % Latest results
                    if strcmp(Res,'New')
                       app.ResultLine(1,i) = plot(app.PlotAxes(i), NaN, NaN, Linewidth=1.5, DisplayName='Latest');
                    elseif strcmp(Res,'Old')
                        app.ResultLine(1,i) = plot(app.PlotAxes(i),app.SH.Results.Time{i},...
                            app.SH.Results.Data{i}, Linewidth=1.5, DisplayName='Latest');
                        app.ShowLatMenu(i).Checked = 'on';
                        app.HideLatMenu(i).Checked = 'on';
                    end
                    % Past results
                    app.PastLine(1,i) = plot(app.PlotAxes(i), NaN, NaN,...
                        Color=app.DarkRed, Linewidth=1.5, HandleVisibility='off',...
                        DisplayName='Past');
                    % Difference in results
                    app.DiffLine(1,i) = plot(app.PlotAxes(i), NaN, NaN,...
                        Color=app.Orange, Linestyle=':', Linewidth=1.5,...
                        HandleVisibility='off', DisplayName='Difference');
            end
            legend(app.PlotAxes(i), Box='off');
        end

        % Context menu item: hides the selected lines from the selected
        % plot
        function hideLineMenuSelected(app, source, ~)

            i = str2double(source.Parent.Parent.Tag);
            switch source.Text
                case 'Latest Results'
                    app.ShowLatMenu(i).Checked = 'off';
                    app.HideLatMenu(i).Checked = 'off';
                    l = app.ResultLine;
                case 'Past Results'
                    app.ShowResMenu(i).Checked = 'off';
                    app.HideResMenu(i).Checked = 'off';
                    l = app.PastLine;
                case 'Difference'
                    app.ShowDifMenu(i).Checked = 'off';
                    app.HideDifMenu(i).Checked = 'off';
                    l = app.DiffLine;
            end

            switch i
                % Velocity, engine speed and engine torque have
                % two lines
                case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                        app.SH.Map("EngSpd, MotSpd (RPM)")
                        app.SH.Map("EngTrq, MotTrq (Nm)")}
                    set(l(:,i), XData=NaN, YData=NaN, HandleVisibility='off');
                otherwise
                    set(l(1,i), XData=NaN, YData=NaN, HandleVisibility='off');
            end

        end

        % Callback function activated when Drive Cycle line is clicked on.
        % It reconfigures the line according to the movement of the mouse.
        function lineSelectedCallback(app)
            if strcmp(app.DriveCycleFromDropDown.Value, 'Wide Open Throttle (WOT)')
                % Margin of error within which vertical line can be selected
                Margin = 0.3;
                % Find which point on the axes was clicked
                SelectedTime = app.DriveCycleAxes.CurrentPoint;
                % Determine which part of the plot is to be modified. dir =
                % 1/2 (x- and y- directions). par = parameter to be changed
                if SelectedTime(1,1) <= app.SH.TMP.StartTime - Margin
                    dir = 2;
                    par = "InSpeed";
                    id = 0;
                elseif SelectedTime(1,1) <= app.SH.TMP.StartTime + 0.1 + Margin
                    dir = 1;
                    par = "StartTime";
                    id  = 1;
                elseif SelectedTime(1,1) <= app.SH.TMP.DecTime - Margin
                    dir = 2;
                    par = "RefSpeed";
                    id = 0;
                elseif SelectedTime(1,1) <= app.SH.TMP.DecTime + 0.1 + Margin
                    dir = 1;
                    par = "DecTime";
                    id = 2;
                else
                    dir = 2;
                    par = "FinSpeed";
                    id = 0;
                end
                % Change pointer according to selected segment
                PointerType = {'left','top'};
                app.UIFigure.Pointer = PointerType{dir};
                % Create callbacks
                app.UIFigure.WindowButtonMotionFcn = @(~,~) moveDriveCycleLine;
                app.UIFigure.WindowButtonUpFcn = @restoreDef;
            end
            % Move line according to movement of the mouse
            function moveDriveCycleLine
                val = max(0.1, round(app.DriveCycleAxes.CurrentPoint(1, dir), 1));
                % Edge cases: acceleration time must not exceed deceleration
                % time (case 2), and deceleration time must not exceed
                % final time (case 4)
                switch id
                    case 1
                        val = min(app.SH.TMP.DecTime - 0.1, val);
                    case 2
                        val = max(app.SH.TMP.StartTime + 0.1, min(app.SH.TMP.WOTTime - 0.1, val));
                end
                app.SH.TMP.(par) = val;
                app.(par).Value = val;
            end
            % Remove callbacks when user releases button
            function restoreDef(~,~)
                app.UIFigure.Pointer = 'arrow';
                app.UIFigure.WindowButtonMotionFcn = [];
                app.UIFigure.WindowButtonUpFcn     = [];
            end
        end % lineSelectedCallback

        function newSessionProcedures(app)
            app.DriveCycleSetupTab.Parent = app.TabGroup;

            % Show tabs
            app.TabGroup.SelectedTab = app.DriveCycleSetupTab;
            app.DriveCycleSetupTab.Parent            = app.TabGroup;
            app.ModelParametersSetupTab.Parent       = app.TabGroup;
            app.SimulationandVisualizationTab.Parent = app.TabGroup;
            app.SessionLogTab.Parent                 = app.TabGroup;

            % Enable save menu
            app.Session.SaveMenu.Enable = true;
            app.Session.SaveAsMenu.Enable = true;
            app.SimulationMenu.Visible = true;

            % Initialize arrays with size equal to number of outputs
            app.PlotQuantity = false(app.SH.NOutputs,1);
            app.PlotAxes = gobjects(app.SH.NOutputs,1);
        end
        
        function onChangedSession(app)
            
            if ~app.Session.isSession
                % If no prior session is present
                newSessionProcedures(app)
            end
            
            % Remove all plots
            clearAllPlotChecks(app)
            
            % Initialize results
            resetResults(app.SH)
            
            for i=1:app.SH.NOutputs
                set(app.ShowLatMenu(i), Enable=false)
                set(app.HideLatMenu(i), Enable=false)
                set(app.ShowResMenu(i), Enable=false)
                set(app.HideResMenu(i), Enable=false)
                set(app.ShowDifMenu(i), Enable=false)
                set(app.HideDifMenu(i), Enable=false)
            end
            
            app.PastResultsLabel.Parent = [];
        end

        function onDriveCycleChanged(app)
            % Update table
            app.DriveCycleTable.Data = [app.SH.TMP.DriveCycle.Time app.SH.TMP.DriveCycle.Data];
            % Update plot
            app.DriveCycleLine.XData = app.SH.TMP.DriveCycle.Time;
            app.DriveCycleLine.YData = app.SH.TMP.DriveCycle.Data;
            % Update plot title
            switch app.SH.TMP.DriveCycleType
                case 'FTP75'
                    app.DriveCycleAxes.Title.String = "FTP75 Drive Cycle";
                    app.WideOpenThrottleWOTPanel.Parent = [];
                    app.GridLayout56.Parent = [];
                    app.DriveCycleAxes.YLimMode = 'auto';
                case 'Wide Open Throttle (WOT)'
                    app.DriveCycleAxes.YLim = [-3 5+...
                        max([app.SH.TMP.InSpeed...
                        app.SH.TMP.RefSpeed...
                        app.SH.TMP.FinSpeed])];
                    app.DriveCycleAxes.Title.String = "Wide Open Throttle (WOT)";
                    app.WideOpenThrottleWOTPanel.Parent = app.GridLayout46;
                    app.GridLayout56.Parent = app.GridLayout46;
                case 'Custom'
                    app.DriveCycleFromDropDown.Value = "Custom";
                    app.DriveCycleAxes.YLimMode = 'auto';
                    app.DriveCycleAxes.Title.String = "Custom Drive Cycle";
                    app.WideOpenThrottleWOTPanel.Parent = [];
                    app.GridLayout56.Parent = app.GridLayout46;
            end
            % Update simulation time
            app.EndSimulationTimeEditField.Value = app.SH.TMP.EndSimulationTime;
            app.RepeatcyclicallyCheckBox.Value = app.SH.TMP.EndSimulationTime > app.SH.TMP.DriveCycle.Time(end);
        end

        function onEngineChanged(app)
            switch app.SH.TMP.Engine
                case 'SiMappedEngine'
                    app.EngineTable.Data = app.SH.TMP.SiMappedEngineTabData;
                case 'SiEngine'
                    app.EngineTable.Data = app.SH.TMP.SiEngineTabData;
            end
        end
        
        function onError(app, ~, e)
            uialert(app.UIFigure, e.Data{1}, e.Data{2}, Icon="error", Interpreter="html");
        end
        
        function onResultsExported(app, ~, e)
            addLogMessage(app, 'ResultsExported', e.Data);
        end

        function onInitialSOCChanged(app)
            app.BatteryTable.Data(contains(app.BatteryTable.Data(:,1),'BattCapInit'), 2) = ...
                {num2str(app.InitialBatterySOCSlider.Value*app.SH.TMP.BatteryParam.('BattChargeMax')/100)};
        end

        function onLoadSession(app, ~, e)
            
            onChangedSession(app)
            
            % Create listeners with new TMP object
            addTMPListeners(app)
            setDriveCycle(app.SH.TMP, app.DriveCycleTable.Data(:,1), app.DriveCycleTable.Data(:,2))
            
            % Log data
            addLogMessage(app,'LoadedSession', e.Data);
        end

        function onNewSession(app)
            
            onChangedSession(app)
            
            % Default simulation handler
            restore(app.SH)
                        
            % Add log information
            addLogMessage(app,'NewSession');
        end

        function onSavedAsSession(app, ~, e)
            addLogMessage(app,'SavedAsSession', e.Data);
        end

        function onSavedSession(app)
            addLogMessage(app,'SavedSession');
        end

        function onPastResultsLoaded(app, ~, e)
            % Enable context menu items
            for i = 1:app.SH.NOutputs
                app.ShowResMenu(i).Enable = true;
                app.HideResMenu(i).Enable = true;
            end
            
            % Enable diff menu only if a simulation was run before
            if app.SH.WasSimulating
                for i = 1:app.SH.NOutputs
                    app.ShowDifMenu(i).Enable = true;
                    app.HideDifMenu(i).Enable = true;
                end
            end
            
            app.PastResultsLabel.Parent = app.GridLayout51;
            app.PastResultsLabel.Text = ['Past results loaded from: ' e.Data];
            addLogMessage(app,'LoadedResults', e.Data);
        end

        function onResultModeChanged(app)
            if app.SH.ShowLive
                app.SimulationLamp.Parent = app.GridLayout17;
                app.StatusLabel.Parent = app.GridLayout17;
            else
                app.SimulationLamp.Parent = [];
                app.StatusLabel.Parent = [];
            end
        end

        function onSimulationPaused(app)
            updateSimCtrlPanel(app, SimBtnIcn="resume.svg", SimBtnTxt="Resume", StopBtnEnbl="on", LogId="SimulationPaused", BackgroundColor=[.47 .67 .19])
            setSimulationLamp(app, [.5 .5 .5], 'Simulation paused')
        end % onSimulationPaused

        function onSimulationEnded(app)
            updateSimCtrlPanel(app, SimBtnIcn="run.svg", SimBtnTxt="Start", StopBtnEnbl="off", LogId="SimulationCompleted", BackgroundColor=[.47 .67 .19])

            setSimulationLamp(app, [0,0.46,0.66], 'Ready to simulate')
            % If was first simulation, enable report generation and export
            % results
            if app.SH.IsPastResultsLoaded
                for i = 1:app.SH.NOutputs
                    app.ShowDifMenu(i).Enable = true;
                    app.HideDifMenu(i).Enable = true;
                end
            end
            
            for i = 1:app.SH.NOutputs
                app.ShowLatMenu(i).Enable = true;
                app.HideLatMenu(i).Enable = true;
            end
            app.GenerateReportMenu.Enable = true;
            app.ExportResultsMenu.Enable = true;
            drawnow
        end

        function onSimulationStarted(app)
            setSimulationLamp(app, app.Green, "Starting simulation...")
            updateSimCtrlPanel(app, SimBtnIcn="pause.svg", SimBtnTxt="Pause", StopBtnEnbl="on", LogId="SimulationStarted", BackgroundColor=[.84 .53 .15])
            drawnow
        end
        
        function onEndSimulationTimeChanged(app)
            app.RepeatcyclicallyCheckBox.Value = app.SH.TMP.EndSimulationTime > app.SH.TMP.DriveCycle.Time(end);
        end

        function onUnitsChanged(app)
            app.DriveCycleTable.ColumnName{2} = ['Velocity (' app.SH.TMP.Units ')'];
            app.DriveCycleAxes.YLabel.String  = ['Velocity (' app.SH.TMP.Units ')'];
            app.SourceVelocityUnitsDropDown.Value = app.SH.TMP.Units;
        end

        % Control simulation lamp color and text
        function setSimulationLamp(app, Color, Text)
            app.SimulationLamp.Color  = Color;
            app.StatusLabel.FontColor = Color;
            app.StatusLabel.Text      = Text;
        end
        
        % Context menu item: show results (latest, past, difference)
        function showLineMenuSelected(app, source, ~)
            
            i = str2double(source.Parent.Parent.Tag);
            % set l = line object, t = time array, d = data array
            switch source.Text
                case 'Latest Results'
                    app.ShowLatMenu(i).Checked = 'on';
                    app.HideLatMenu(i).Checked = 'on';
                    l = app.ResultLine;
                    t = app.SH.Results.Time{i};
                    d = app.SH.Results.Data{i};
                case 'Past Results'
                    app.ShowResMenu(i).Checked = 'on';
                    app.HideResMenu(i).Checked = 'on';
                    l = app.PastLine;
                    t = app.SH.PastResults.Time{i};
                    d = app.SH.PastResults.Data{i};
                case 'Difference'
                    app.ShowDifMenu(i).Checked = 'on';
                    app.HideDifMenu(i).Checked = 'on';
                    % Find which simulation has the lowest time
                    [~, idx] = min([app.SH.PastResults.Time{i}(end) app.SH.Results.Time{i}(end)]);
                    
                    % Construct useful variables for later manipulation
                    Time = {app.SH.PastResults.Time{i} app.SH.Results.Time{i}};
                    Res  = {app.SH.PastResults.Data app.SH.Results.Data};
                    
                    % Find unique times for interpolation
                    [Time{idx}, UniqueIdx]    = unique(Time{idx});
                    [Time{3-idx}, UniqueIdx2] = unique(Time{3-idx});
                    
                    % Construct array of results to interpolate to shorter
                    % time array
                    FittedResult   = Res{idx};
                    ResultToInterp = Res{3 - idx};
                    ResultToInterp = ResultToInterp{i}(:,UniqueIdx2);
                    
                    l = app.DiffLine;
                    t = Time{idx};
                    if i <= 3
                        d = -FittedResult{i}(1,UniqueIdx) + ...
                            interp1(Time{3 - idx}, ResultToInterp(1,:),Time{idx});
                        d = [d; -FittedResult{i}(2,UniqueIdx) + ...
                            interp1(Time{3 - idx}, ResultToInterp(2,:),Time{idx})];
                    else
                        d = -FittedResult{i}(:,UniqueIdx) + ...
                            interp1(Time{3 - idx}',ResultToInterp,Time{idx}');
                    end
            end

            switch i
                case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                        app.SH.Map("EngSpd, MotSpd (RPM)")
                        app.SH.Map("EngTrq, MotTrq (Nm)")}
                    set(l(1,i), XData=t, YData=d(1,:), HandleVisibility='on');
                    set(l(2,i), XData=t, YData=d(2,:), HandleVisibility='on');
                otherwise
                    set(l(1,i), XData=t, YData=d, HandleVisibility='on');
            end
        end

        function updatePlotTiles(app, OutputName, ToPlot)
            idx = app.SH.Map(OutputName);
            app.PlotQuantity(idx) = ToPlot;
            if ToPlot
                % Add tile
                app.PlotAxes(idx) = nexttile(app.TileContainer);
                hold(app.PlotAxes(idx),'on')
                grid(app.PlotAxes(idx),'on')
                app.PlotAxes(idx).Box = 'on';
                app.PlotAxes(idx).FontName = 'Courier New';
                app.PlotAxes(idx).Color = [.95 .95 .95];
                app.PlotAxes(idx).Title.String = OutputName;
                app.PlotAxes(idx).ContextMenu  = app.ContextMenu(idx);
                % Parentize the relevant lines
                if app.SH.WasSimulating
                    createTileLines(app, idx, 'Old')
                else
                    createTileLines(app, idx, 'New');
                end
            else
                % Remove tile
                delete(app.PlotAxes(idx))
            end
        end

        function updateSimCtrlPanel(app, opts)

            arguments
                app
                opts.SimBtnIcn
                opts.SimBtnTxt string {mustBeMember(opts.SimBtnTxt, ["Start","Pause","Resume"])}
                opts.StopBtnEnbl matlab.lang.OnOffSwitchState
                opts.LogId
                opts.BackgroundColor
            end
            
            % Update start button according to simulation status
            set(app.StartButton, ...
                Icon = opts.SimBtnIcn, ...
                Text = opts.SimBtnTxt, ...
                Tooltip = opts.SimBtnTxt + " simulation", ...
                BackgroundColor = opts.BackgroundColor);

            % Enable/disable button based on simulation status
            app.StopButton.Enable = opts.StopBtnEnbl;

            addLogMessage(app, opts.LogId)
            drawnow
        end

        function updateSimTime(app)
            app.StatusLabel.Text = "Simulating (" + round(app.SH.ProgressPercentage)  + "%)";
        end

        function updatePlots(app)
            for opIdx = 1:app.SH.NOutputs
                if app.PlotQuantity(opIdx)
                    if ~isempty(app.SH.Results.Time{opIdx})
                        switch opIdx
                            case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                                    app.SH.Map("EngSpd, MotSpd (RPM)")
                                    app.SH.Map("EngTrq, MotTrq (Nm)")}
                                set(app.ResultLine(1,opIdx), XData=app.SH.Results.Time{opIdx},...
                                    YData=app.SH.Results.Data{opIdx}(1,:));
                                set(app.ResultLine(2,opIdx), XData=app.SH.Results.Time{opIdx},...
                                    YData=app.SH.Results.Data{opIdx}(2,:));
                            otherwise
                                set(app.ResultLine(1,opIdx), XData=app.SH.Results.Time{opIdx},...
                                    YData=app.SH.Results.Data{opIdx});
                        end
                        app.ShowLatMenu(opIdx).Checked = 'on';
                        app.HideLatMenu(opIdx).Checked = 'on';
                    end
                end
            end
            drawnow limitrate
        end % updatePlots
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place figure at the centre of the screen
            g = groot; p = app.UIFigure.Position(3:4);
            app.UIFigure.Position = [(g.ScreenSize(3:4)-p)/2 p];
            
            % Create simulation controller
            app.SH = SimulationHandler;
            addlistener(app.SH,"SimulationErrored",  @app.onError);
            addlistener(app.SH,"PastResultsLoaded",  @app.onPastResultsLoaded);
            addlistener(app.SH,"ResultsExported",    @app.onResultsExported);
            addlistener(app.SH,"SimulationStarted",  @(~,~) app.onSimulationStarted);
            addlistener(app.SH,"SimulationPaused",   @(~,~) app.onSimulationPaused);
            addlistener(app.SH,"SimulationEnded",    @(~,~) app.onSimulationEnded);
            addlistener(app.SH,"ShowLive","PostSet", @(~,~) app.onResultModeChanged);
            addlistener(app.SH,"ResultsUpdated",     @(~,~) app.updatePlots);
            addlistener(app.SH,"ProgressPercentage","PostSet",@(~,~) app.updateSimTime);
            addTMPListeners(app) % Add listeners to tunable model parameters

            % Initialize drive cycle line
            app.DriveCycleLine = line(app.DriveCycleAxes, NaN, NaN, ...
                Linewidth = 1.5, ...
                Color = [0.84,0.53,0.15], ...
                ButtonDownFcn = @(~,~) app.lineSelectedCallback);

            % Ready to simulate!
            setSimulationLamp(app, [0,0.46,0.66], 'Ready to simulate')

            % Initialize WOT field values
            app.StartTime.Value = app.SH.TMP.StartTime;
            app.InSpeed.Value   = app.SH.TMP.InSpeed;
            app.RefSpeed.Value  = app.SH.TMP.RefSpeed;
            app.FinSpeed.Value  = app.SH.TMP.FinSpeed;
            app.DecTime.Value   = app.SH.TMP.DecTime;
            app.WOTTime.Value   = app.SH.TMP.WOTTime;

            % Initialize environment and vehicle parameters
            app.TemperatureSpinner.Value             = app.SH.TMP.Temperature;
            app.AtmosphericPressureSlider.Value      = app.SH.TMP.Pressure;
            app.GradeEditField.Value                 = app.SH.TMP.Grade;
            app.LongitudinalWindSpeedEditField.Value = app.SH.TMP.WindSpeed;
            app.LoadedWheelRadiusEditField.Value     = app.SH.TMP.LoadedRadius;
            app.UnloadedWheelRadiusEditField.Value   = app.SH.TMP.UnloadedRadius;
            app.VehicleMassEditField.Value           = app.SH.TMP.VehicleMass;
            app.InitialBatterySOCSlider.Value        = app.SH.TMP.InitialSOC;
            app.EndSimulationTimeEditField.Value     = app.SH.TMP.EndSimulationTime;

            % Initialize Engine type
            app.DriveCycleFromDropDown.Items = app.SH.TMP.AllowedDriveCycles;
            app.SelectEngineTypeDropDown.Items = app.SH.TMP.AllowedEngines;
            app.SelectEngineTypeDropDown.Value = app.SH.TMP.Engine;
            app.SourceVelocityUnitsDropDown.Items = app.SH.TMP.AllowedUnits;
            app.SourceVelocityUnitsDropDown.Value = app.SH.TMP.Units;

            % Initialize battery table data
            app.BatteryTable.Data = app.SH.TMP.BatteryTable;

            % Show time of login
            app.TimeLogLabel.Text = ['Application started on: ' datestr(datetime)];

            % Create session menu (after initializing graphics objects), disable save, add listeners for
            % load/save, reorder menu items
            app.Session = AppSession(app);
            addlistener(app.Session, "NewSession",     @(~,~) app.onNewSession);
            addlistener(app.Session, "LoadSession",    @app.onLoadSession);
            addlistener(app.Session, "SavedSession",   @(~,~) app.onSavedSession);
            addlistener(app.Session, "SavedAsSession", @app.onSavedAsSession);
            app.Session.SaveMenu.Enable = false;
            app.Session.SaveAsMenu.Enable = false;
            app.UIFigure.Children = app.UIFigure.Children([3 4 1 2]);

            % Hide tabs
            app.DriveCycleSetupTab.Parent            = [];
            app.ModelParametersSetupTab.Parent       = [];
            app.SimulationandVisualizationTab.Parent = [];
            app.SessionLogTab.Parent                 = [];
            app.ReportTab.Parent                     = [];
            app.WideOpenThrottleWOTPanel.Parent      = [];
            app.GridLayout56.Parent                  = [];
            app.PastResultsLabel.Parent              = [];

            % Create ContextMenu for plots (one for each plot) in
            % Simulation and Visualization tab
            for i = 1:app.SH.NOutputs
                app.ContextMenu(i) = uicontextmenu(app.UIFigure, Tag=num2str(i));
                app.ShowMenu(i)    = uimenu(app.ContextMenu(i), Text="Show");
                app.ShowResMenu(i) = uimenu(app.ShowMenu(i), MenuSelectedFcn=@app.showLineMenuSelected, Text="Past Results", Enable="off");
                app.ShowLatMenu(i) = uimenu(app.ShowMenu(i), MenuSelectedFcn=@app.showLineMenuSelected, Text="Latest Results", Enable="off");
                app.ShowDifMenu(i) = uimenu(app.ShowMenu(i), MenuSelectedFcn=@app.showLineMenuSelected, Text="Difference", Enable="off");
                app.HideMenu(i)    = uimenu(app.ContextMenu(i), Text="Hide");
                app.HideResMenu(i) = uimenu(app.HideMenu(i), MenuSelectedFcn=@app.hideLineMenuSelected, Text="Past Results", Enable="off");
                app.HideLatMenu(i) = uimenu(app.HideMenu(i), MenuSelectedFcn=@app.hideLineMenuSelected, Text="Latest Results", Enable="off");
                app.HideDifMenu(i) = uimenu(app.HideMenu(i), MenuSelectedFcn=@app.hideLineMenuSelected, Text="Difference", Enable="off");
            end

            % Initialize Tile Container
            app.TileContainer = tiledlayout("flow", Parent=app.Panel, Padding="compact", TileSpacing="compact");
        end

        % Menu selected function: DocumentationonHEVP4Menu
        function DocumentationonHEVP4MenuSelected(app, event)
            % Open Documentation page on HEV P4 model
            web('https://www.mathworks.com/help/autoblks/ug/explore-the-hybrid-electric-vehicle-p4-reference-application.html')
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            newMenuSelected(app.Session)
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            loadMenuSelected(app.Session)
        end

        % Value changed function: EndSimulationTimeEditField
        function EndSimulationTimeEditFieldValueChanged(app, event)
            app.SH.TMP.EndSimulationTime = app.EndSimulationTimeEditField.Value;
        end

        % Cell edit callback: DriveCycleTable
        function DriveCycleTableCellEdit(app, event)
            setDriveCycle(app.SH.TMP, app.DriveCycleTable.Data(:,1), app.DriveCycleTable.Data(:,2))
        end

        % Button pushed function: DriveCycleFromFileButton
        function DriveCycleFromFileButtonPushed(app, event)
            loadDriveCycle(app.SH.TMP, "Custom")
        end

        % Value changed function: DriveCycleFromDropDown
        function DriveCycleFromDropDownValueChanged(app, event)
            loadDriveCycle(app.SH.TMP, app.DriveCycleFromDropDown.Value)
        end

        % Value changed function: StartTime
        function StartTimeValueChanged(app, event)
            app.SH.TMP.StartTime = app.StartTime.Value;
        end

        % Value changed function: InSpeed
        function InSpeedValueChanged(app, event)
            app.SH.TMP.InSpeed = app.InSpeed.Value;
        end

        % Value changed function: RefSpeed
        function RefSpeedValueChanged(app, event)
            app.SH.TMP.RefSpeed = app.RefSpeed.Value;
        end

        % Value changed function: DecTime
        function DecTimeValueChanged(app, event)
            app.SH.TMP.DecTime = app.DecTime.Value;
        end

        % Value changed function: FinSpeed
        function FinSpeedValueChanged(app, event)
            app.SH.TMP.FinSpeed = app.FinSpeed.Value;
        end

        % Value changed function: WOTTime
        function WOTTimeValueChanged(app, event)
            app.SH.TMP.WOTTime = app.WOTTime.Value;
        end

        % Value changed function: SourceVelocityUnitsDropDown
        function SourceVelocityUnitsDropDownValueChanged(app, event)
            app.SH.TMP.Units = app.SourceVelocityUnitsDropDown.Value;
        end

        % Value changed function: InitialBatterySOCSlider
        function InitialBatterySOCSliderValueChanged(app, event)
            app.SH.TMP.InitialSOC = app.InitialBatterySOCSlider.Value;
        end

        % Value changed function: SelectEngineTypeDropDown
        function SelectEngineTypeDropDownValueChanged(app, event)
            app.SH.TMP.Engine = app.SelectEngineTypeDropDown.Value;
        end

        % Value changed function: VehicleSpeedCheckBox
        function VehicleSpeedCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Trace Velocity, Target, Actual (mph)", app.VehicleSpeedCheckBox.Value)
        end

        % Value changed function: EngineMotorTorqueCheckBox
        function EngineMotorTorqueCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "EngTrq, MotTrq (Nm)", app.EngineMotorTorqueCheckBox.Value)
        end

        % Value changed function: EngineMotorSpeedCheckBoxnn
        function EngineMotorSpeedCheckBoxnnValueChanged(app, event)
            updatePlotTiles(app, "EngSpd, MotSpd (RPM)", app.EngineMotorSpeedCheckBoxnn.Value)
        end

        % Value changed function: BatterySOCCheckBox
        function BatterySOCCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Battery SOC", app.BatterySOCCheckBox.Value)
        end

        % Value changed function: BatteryCurrentCheckBox
        function BatteryCurrentCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Battery Current (A)", app.BatteryCurrentCheckBox.Value)
        end

        % Value changed function: HCgmiCheckBox
        function HCgmiCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "HC (g/mi)", app.HCgmiCheckBox.Value)
        end

        % Value changed function: COgmiCheckBox
        function COgmiCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "CO (g/mi)", app.COgmiCheckBox.Value)
        end

        % Value changed function: NOxgmiCheckBox
        function NOxgmiCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "NOx (g/mi)", app.NOxgmiCheckBox.Value)
        end

        % Value changed function: CO2gkmCheckBox
        function CO2gkmCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "CO2 (g/km)", app.CO2gkmCheckBox.Value)
        end

        % Value changed function: USFuelEconomyCheckBox
        function USFuelEconomyCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Fuel Economy (US MPGe)", app.USFuelEconomyCheckBox.Value)
        end

        % Value changed function: FuelEconomyL100kmCheckBox
        function FuelEconomyL100kmCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Fuel Economy (L/100km)", app.FuelEconomyL100kmCheckBox.Value)
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            clearAllPlotChecks(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            toggleExecution(app.SH)
        end

        % Callback function
        function SwitchValueChanged(app, event)
            app.SH.ShowLive = strcmp(app.Switch.Value, "Live");
        end

        % Value changed function: LongitudinalWindSpeedEditField
        function LongitudinalWindSpeedEditFieldValueChanged(app, event)
            updateSimulationParameter(app.SH, "WindSpeed", app.LongitudinalWindSpeedEditField.Value)
        end

        % Value changed function: GradeEditField
        function GradeEditFieldValueChanged(app, event)
            updateSimulationParameter(app.SH, "Grade", app.GradeEditField.Value)
        end

        % Value changed function: TemperatureSpinner
        function TemperatureSpinnerValueChanged(app, event)
            updateSimulationParameter(app.SH, "Temperature", app.TemperatureSpinner.Value)
        end

        % Value changed function: AtmosphericPressureSlider
        function AtmosphericPressureSliderValueChanged(app, event)
            updateSimulationParameter(app.SH, "Pressure", app.AtmosphericPressureSlider.Value)
        end

        % Value changed function: LoadedWheelRadiusEditField
        function LoadedWheelRadiusEditFieldValueChanged(app, event)
            updateSimulationParameter(app.SH, "LoadedRadius", app.LoadedWheelRadiusEditField.Value)
        end

        % Value changed function: UnloadedWheelRadiusEditField
        function UnloadedWheelRadiusEditFieldValueChanged(app, event)
            updateSimulationParameter(app.SH, "UnloadedRadius", app.UnloadedWheelRadiusEditField.Value)
        end

        % Value changed function: VehicleMassEditField
        function VehicleMassEditFieldValueChanged(app, event)
            updateSimulationParameter(app.SH, "VehicleMass", app.VehicleMassEditField.Value)
        end

        % Menu selected function: GenerateReportMenu
        function GenerateReportMenuSelected(app, event)
            % The axis called by the report generator (in Report Tab) needs to have a
            % parent
            app.ReportTab.Parent = app.TabGroup;

            ProgressBar = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                Message='Creating simulation report...', ShowPercentage=true);
            
            % Create simulation report
            fid = HEVReportGen(app.SH, app.Session.Username, app.ReportAxes, app.DriveCycleAxes, ProgressBar);

            ProgressBar.Value = 1;
            close(ProgressBar)

            if ~fid
                % Report generation failed
                uialert(app.UIFigure, lasterr, 'Error') %#ok<*LERR>
                addLogMessage(app, "ReportFailed")
            else
                % Report generation successful
                uialert(app.UIFigure, "Report generated successfully.", "Report Outcome", Icon="success")
                addLogMessage(app, "ReportGenerated")
            end
            app.ReportTab.Parent = [];
        end

        % Menu selected function: LoadResultsMenu
        function LoadResultsMenuSelected(app, event)
            loadResults(app.SH)
        end

        % Menu selected function: ExportResultsMenu
        function ExportResultsMenuSelected(app, event)
            exportResults(app.SH)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stopSimulation(app.SH)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'HEV P4 Reference Application';
            app.UIFigure.Icon = 'plotpicker-idnlhwplot.png';
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Visible = 'off';
            app.SimulationMenu.Text = 'Simulation';
            app.ExportResultsMenu = uimenu(app.SimulationMenu);
            app.ExportResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResultsMenuSelected, true);
            app.ExportResultsMenu.Enable = 'off';
            app.ExportResultsMenu.Accelerator = 'E';
            app.ExportResultsMenu.Text = 'Export Results...';
            app.LoadResultsMenu = uimenu(app.SimulationMenu);
            app.LoadResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadResultsMenuSelected, true);
            app.LoadResultsMenu.Accelerator = 'R';
            app.LoadResultsMenu.Text = 'Load Results...';
            app.GenerateReportMenu = uimenu(app.SimulationMenu);
            app.GenerateReportMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateReportMenuSelected, true);
            app.GenerateReportMenu.Enable = 'off';
            app.GenerateReportMenu.Separator = 'on';
            app.GenerateReportMenu.Accelerator = 'G';
            app.GenerateReportMenu.Text = 'Generate Report';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationonHEVP4Menu = uimenu(app.HelpMenu);
            app.DocumentationonHEVP4Menu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationonHEVP4MenuSelected, true);
            app.DocumentationonHEVP4Menu.Accelerator = 'H';
            app.DocumentationonHEVP4Menu.Text = 'Documentation on HEV P4';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.GridLayout38 = uigridlayout(app.StartTab);
            app.WelcomeLabel = uilabel(app.GridLayout38);
            app.WelcomeLabel.Text = 'Welcome! Start a new session or load a pre-saved session';
            app.NewSessionButton = uibutton(app.GridLayout38, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Icon = fullfile(pathToMLAPP, 'pics', 'add.svg');
            app.NewSessionButton.Tooltip = {'Click to start a new session'};
            app.NewSessionButton.Text = 'New Session';
            app.LoadSessionButton = uibutton(app.GridLayout38, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Icon = fullfile(pathToMLAPP, 'pics', 'loadWorkspace.svg');
            app.LoadSessionButton.Tooltip = {'Click to load a saved session'};
            app.LoadSessionButton.Text = 'Load Session';
            app.HTML = uihtml(app.GridLayout38);
            app.HTML.HTMLSource = 'modelWebView.html';
            app.DriveCycleSetupTab = uitab(app.TabGroup);
            app.DriveCycleSetupTab.Title = 'Drive Cycle Setup';
            app.GridLayout39 = uigridlayout(app.DriveCycleSetupTab);
            app.GridLayout40 = uigridlayout(app.GridLayout39);
            app.DriveCycleAxes = uiaxes(app.GridLayout40);
            title(app.DriveCycleAxes, 'FTP75 Drive Cycle')
            xlabel(app.DriveCycleAxes, 'Time (s)')
            ylabel(app.DriveCycleAxes, 'Velocity (m/s)')
            app.DriveCycleTable = uitable(app.GridLayout40);
            app.DriveCycleTable.ColumnName = {'Time (s)'; 'Velocity (m/s)'};
            app.DriveCycleTable.RowName = {};
            app.DriveCycleTable.ColumnEditable = [false true];
            app.DriveCycleTable.CellEditCallback = createCallbackFcn(app, @DriveCycleTableCellEdit, true);
            app.DriveCycleTable.Tooltip = {'Drive cycle profile. Velocity data can be modified directly from the table.'};
            app.GridLayout41 = uigridlayout(app.GridLayout39);
            app.GridLayout30 = uigridlayout(app.GridLayout41);
            app.SelectdrivecycleLabel = uilabel(app.GridLayout30);
            app.SelectdrivecycleLabel.Text = 'Select drive cycle:';
            app.DriveCycleFromDropDown = uidropdown(app.GridLayout30);
            app.DriveCycleFromDropDown.Items = {'Select...'};
            app.DriveCycleFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DriveCycleFromDropDownValueChanged, true);
            app.DriveCycleFromDropDown.Tooltip = {'Choose from pre-defined drive cycles'};
            app.DriveCycleFromDropDown.Value = 'Select...';
            app.DriveCycleFromFileButton = uibutton(app.GridLayout30, 'push');
            app.DriveCycleFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @DriveCycleFromFileButtonPushed, true);
            app.DriveCycleFromFileButton.Icon = fullfile(pathToMLAPP, 'pics', 'browseFolder.svg');
            app.DriveCycleFromFileButton.Tooltip = {'Import drive cycle from file (.mat, .xlsx, .xls, .txt)'};
            app.DriveCycleFromFileButton.Text = 'From File...';
            app.DriveCycleOptionsPanel = uipanel(app.GridLayout41);
            app.DriveCycleOptionsPanel.Title = '   Drive Cycle Options';
            app.GridLayout46 = uigridlayout(app.DriveCycleOptionsPanel);
            app.GridLayout47 = uigridlayout(app.GridLayout46);
            app.EndSimulationTimesEditFieldLabel = uilabel(app.GridLayout47);
            app.EndSimulationTimesEditFieldLabel.Text = 'End Simulation Time (s):';
            app.EndSimulationTimeEditField = uieditfield(app.GridLayout47, 'numeric');
            app.EndSimulationTimeEditField.Limits = [0 Inf];
            app.EndSimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndSimulationTimeEditFieldValueChanged, true);
            app.RepeatcyclicallyCheckBox = uicheckbox(app.GridLayout47);
            app.RepeatcyclicallyCheckBox.Enable = 'off';
            app.RepeatcyclicallyCheckBox.Text = 'Repeat cyclically';
            app.WideOpenThrottleWOTPanel = uipanel(app.GridLayout46);
            app.WideOpenThrottleWOTPanel.Title = '   Wide Open Throttle (WOT)';
            app.GridLayout49 = uigridlayout(app.WideOpenThrottleWOTPanel);
            app.StartTimeLabel = uilabel(app.GridLayout49);
            app.StartTimeLabel.Text = 'Start time, t_wot1 [s]:';
            app.StartTime = uieditfield(app.GridLayout49, 'numeric');
            app.StartTime.Limits = [0 Inf];
            app.StartTime.ValueChangedFcn = createCallbackFcn(app, @StartTimeValueChanged, true);
            app.StartTime.Tooltip = {'Modify from here or by dragging the line in the plot below.'};
            app.DecTimeLabel = uilabel(app.GridLayout49);
            app.DecTimeLabel.Text = 'Time to start deceleration, t_wot2 [s]:';
            app.DecTime = uieditfield(app.GridLayout49, 'numeric');
            app.DecTime.Limits = [0 Inf];
            app.DecTime.ValueChangedFcn = createCallbackFcn(app, @DecTimeValueChanged, true);
            app.DecTime.Tooltip = {'Modify from here or by dragging the line in the plot below.'};
            app.InSpeedLabel = uilabel(app.GridLayout49);
            app.InSpeedLabel.Text = 'Initial reference speed, xdot_woto []:';
            app.InSpeed = uieditfield(app.GridLayout49, 'numeric');
            app.InSpeed.Limits = [0 Inf];
            app.InSpeed.ValueChangedFcn = createCallbackFcn(app, @InSpeedValueChanged, true);
            app.InSpeed.Tooltip = {'Modify from here or by dragging the line in the plot below.'};
            app.FinSpeedLabel = uilabel(app.GridLayout49);
            app.FinSpeedLabel.Text = 'Final reference speed, xdot_wot2 []:';
            app.FinSpeed = uieditfield(app.GridLayout49, 'numeric');
            app.FinSpeed.Limits = [0 Inf];
            app.FinSpeed.ValueChangedFcn = createCallbackFcn(app, @FinSpeedValueChanged, true);
            app.FinSpeed.Tooltip = {'Modify from here or by dragging the line in the plot below.'};
            app.RefSpeedLabel = uilabel(app.GridLayout49);
            app.RefSpeedLabel.Text = 'Nominal reference speed, xdot_wot1 []:';
            app.RefSpeed = uieditfield(app.GridLayout49, 'numeric');
            app.RefSpeed.Limits = [0 Inf];
            app.RefSpeed.ValueChangedFcn = createCallbackFcn(app, @RefSpeedValueChanged, true);
            app.RefSpeed.Tooltip = {'Modify from here or by dragging the line in the plot below.'};
            app.WOTTimeLabel = uilabel(app.GridLayout49);
            app.WOTTimeLabel.Text = 'WOT simulation time, t_wotend []:';
            app.WOTTime = uieditfield(app.GridLayout49, 'numeric');
            app.WOTTime.Limits = [0 Inf];
            app.WOTTime.ValueChangedFcn = createCallbackFcn(app, @WOTTimeValueChanged, true);
            app.WOTTime.Tooltip = {'Modify from here or by dragging the line in the plot below.'};
            app.GridLayout56 = uigridlayout(app.GridLayout46);
            app.SourceVelocityUnitsDropDown = uidropdown(app.GridLayout56);
            app.SourceVelocityUnitsDropDown.Items = {'Select...'};
            app.SourceVelocityUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceVelocityUnitsDropDownValueChanged, true);
            app.SourceVelocityUnitsDropDown.Value = 'Select...';
            app.SourceVelocityUnitsDropDownLabel = uilabel(app.GridLayout56);
            app.SourceVelocityUnitsDropDownLabel.Text = 'Source Velocity Units:';
            app.ModelParametersSetupTab = uitab(app.TabGroup);
            app.ModelParametersSetupTab.Title = 'Model Parameters Setup';
            app.GridLayout50 = uigridlayout(app.ModelParametersSetupTab);
            app.EnvironmentParametersPanel = uipanel(app.GridLayout50);
            app.EnvironmentParametersPanel.Title = 'Environment Parameters';
            app.GridLayout13 = uigridlayout(app.EnvironmentParametersPanel);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.GradeEditField = uieditfield(app.GridLayout14, 'numeric');
            app.GradeEditField.Limits = [0 Inf];
            app.GradeEditField.ValueChangedFcn = createCallbackFcn(app, @GradeEditFieldValueChanged, true);
            app.TemperatureSpinner = uispinner(app.GridLayout14);
            app.TemperatureSpinner.Limits = [0 Inf];
            app.TemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @TemperatureSpinnerValueChanged, true);
            app.LongitudinalWindSpeedEditField = uieditfield(app.GridLayout14, 'numeric');
            app.LongitudinalWindSpeedEditField.Limits = [0 Inf];
            app.LongitudinalWindSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudinalWindSpeedEditFieldValueChanged, true);
            app.GradeLabel = uilabel(app.GridLayout14);
            app.GradeLabel.Text = 'Grade (deg):';
            app.LongitudinalWindSpeedLabel = uilabel(app.GridLayout14);
            app.LongitudinalWindSpeedLabel.Text = 'Longitudinal Wind Speed (m/s):';
            app.TemperatureLabel = uilabel(app.GridLayout14);
            app.TemperatureLabel.Text = 'Temperature (K):';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.AtmosphericPressureSlider = uislider(app.GridLayout15);
            app.AtmosphericPressureSlider.Limits = [0.6 1];
            app.AtmosphericPressureSlider.MajorTicks = [0.608 0.6898 0.785 0.886 1];
            app.AtmosphericPressureSlider.MajorTickLabels = {'0.608 (4000 m)', '0.690 (3000 m)', '0.785 (2000 m)', '0.886 (1000 m)', '1.000 (Sea Level)'};
            app.AtmosphericPressureSlider.ValueChangedFcn = createCallbackFcn(app, @AtmosphericPressureSliderValueChanged, true);
            app.AtmosphericPressureSlider.MinorTicks = [];
            app.AtmosphericPressureSlider.Value = 1;
            app.AtmosphericPressureLabel = uilabel(app.GridLayout15);
            app.AtmosphericPressureLabel.Text = 'Atmospheric Pressure (atm)';
            app.VehicleParametersPanel = uipanel(app.GridLayout50);
            app.VehicleParametersPanel.Title = 'Vehicle Parameters';
            app.GridLayout23 = uigridlayout(app.VehicleParametersPanel);
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.LoadedWheelRadiusEditField = uieditfield(app.GridLayout24, 'numeric');
            app.LoadedWheelRadiusEditField.Limits = [0 Inf];
            app.LoadedWheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LoadedWheelRadiusEditFieldValueChanged, true);
            app.UnloadedWheelRadiusEditField = uieditfield(app.GridLayout24, 'numeric');
            app.UnloadedWheelRadiusEditField.Limits = [0 Inf];
            app.UnloadedWheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @UnloadedWheelRadiusEditFieldValueChanged, true);
            app.VehicleMassEditField = uieditfield(app.GridLayout24, 'numeric');
            app.VehicleMassEditField.Limits = [0 Inf];
            app.VehicleMassEditField.ValueChangedFcn = createCallbackFcn(app, @VehicleMassEditFieldValueChanged, true);
            app.LoadedWheelRadiusLabel = uilabel(app.GridLayout24);
            app.LoadedWheelRadiusLabel.Text = 'Loaded Wheel Radius (m):';
            app.UnloadedWheelRadiusLabel = uilabel(app.GridLayout24);
            app.UnloadedWheelRadiusLabel.Text = 'Unloaded Wheel Radius (m):';
            app.VehicleMassLabel = uilabel(app.GridLayout24);
            app.VehicleMassLabel.Text = 'Vehicle Mass (kg):';
            app.GridLayout39_2 = uigridlayout(app.GridLayout23);
            app.InitialBatterySOCSlider = uislider(app.GridLayout39_2);
            app.InitialBatterySOCSlider.ValueChangedFcn = createCallbackFcn(app, @InitialBatterySOCSliderValueChanged, true);
            app.InitialBatterySOCSlider.Value = 100;
            app.InitialBatterySOCLabel = uilabel(app.GridLayout39_2);
            app.InitialBatterySOCLabel.Text = 'Initial Battery SOC (%)';
            app.BatteryPanel = uipanel(app.GridLayout50);
            app.BatteryPanel.Title = 'Battery';
            app.GridLayout12 = uigridlayout(app.BatteryPanel);
            app.BatteryTypeLabel = uilabel(app.GridLayout12);
            app.BatteryTypeLabel.Text = '  Battery type: Lithium Ion';
            app.BatteryTable = uitable(app.GridLayout12);
            app.BatteryTable.ColumnName = {'Parameter'; 'Value'};
            app.BatteryTable.RowName = {};
            app.EnginePanel = uipanel(app.GridLayout50);
            app.EnginePanel.Title = 'Engine';
            app.GridLayout34 = uigridlayout(app.EnginePanel);
            app.GridLayout35 = uigridlayout(app.GridLayout34);
            app.SelectEngineTypeDropDownLabel = uilabel(app.GridLayout35);
            app.SelectEngineTypeDropDownLabel.Text = 'Select Engine Type:';
            app.SelectEngineTypeDropDown = uidropdown(app.GridLayout35);
            app.SelectEngineTypeDropDown.Items = {'Select...'};
            app.SelectEngineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEngineTypeDropDownValueChanged, true);
            app.SelectEngineTypeDropDown.Tooltip = {'Select engine type'};
            app.SelectEngineTypeDropDown.Value = 'Select...';
            app.GridLayout36 = uigridlayout(app.GridLayout34);
            app.ECMLabel = uilabel(app.GridLayout36);
            app.ECMLabel.Text = 'Engine Controller Module (ECM): SiEngineController';
            app.EngineTable = uitable(app.GridLayout36);
            app.EngineTable.ColumnName = {'Parameter'; 'Value'};
            app.EngineTable.RowName = {};
            app.SimulationandVisualizationTab = uitab(app.TabGroup);
            app.SimulationandVisualizationTab.Title = 'Simulation and Visualization';
            app.GridLayout51 = uigridlayout(app.SimulationandVisualizationTab);
            app.SimulationControlPanel = uipanel(app.GridLayout51);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.GridLayout17 = uigridlayout(app.SimulationControlPanel);
            app.SimulationLamp = uilamp(app.GridLayout17);
            app.StatusLabel = uilabel(app.GridLayout17);
            app.StartButton = uibutton(app.GridLayout17, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = fullfile(pathToMLAPP, 'pics', 'run.svg');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout17, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'pics', 'stopSimulation.svg');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.GridLayout18 = uigridlayout(app.GridLayout51);
            app.OutputPlotsLabel = uilabel(app.GridLayout18);
            app.OutputPlotsLabel.Text = 'Output Plots:';
            app.VehicleActuatorsPanel = uipanel(app.GridLayout18);
            app.VehicleActuatorsPanel.Title = 'Vehicle / Actuators';
            app.GridLayout50_2 = uigridlayout(app.VehicleActuatorsPanel);
            app.VehicleSpeedCheckBox = uicheckbox(app.GridLayout50_2);
            app.VehicleSpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @VehicleSpeedCheckBoxValueChanged, true);
            app.VehicleSpeedCheckBox.Text = 'Vehicle Speed';
            app.EngineMotorTorqueCheckBox = uicheckbox(app.GridLayout50_2);
            app.EngineMotorTorqueCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineMotorTorqueCheckBoxValueChanged, true);
            app.EngineMotorTorqueCheckBox.Text = 'Engine / Motor Torque (Nm)';
            app.EngineMotorSpeedCheckBoxnn = uicheckbox(app.GridLayout50_2);
            app.EngineMotorSpeedCheckBoxnn.ValueChangedFcn = createCallbackFcn(app, @EngineMotorSpeedCheckBoxnnValueChanged, true);
            app.EngineMotorSpeedCheckBoxnn.Text = 'Engine / Motor Speed (RPM)';
            app.BatteryPanel_2 = uipanel(app.GridLayout18);
            app.BatteryPanel_2.Title = 'Battery';
            app.GridLayout51_2 = uigridlayout(app.BatteryPanel_2);
            app.BatterySOCCheckBox = uicheckbox(app.GridLayout51_2);
            app.BatterySOCCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatterySOCCheckBoxValueChanged, true);
            app.BatterySOCCheckBox.Text = 'Battery SOC';
            app.BatteryCurrentCheckBox = uicheckbox(app.GridLayout51_2);
            app.BatteryCurrentCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatteryCurrentCheckBoxValueChanged, true);
            app.BatteryCurrentCheckBox.Text = 'Battery Current (A)';
            app.EmissionsFuelEconomyPanel = uipanel(app.GridLayout18);
            app.EmissionsFuelEconomyPanel.Title = 'Emissions / Fuel Economy';
            app.GridLayout52 = uigridlayout(app.EmissionsFuelEconomyPanel);
            app.HCgmiCheckBox = uicheckbox(app.GridLayout52);
            app.HCgmiCheckBox.ValueChangedFcn = createCallbackFcn(app, @HCgmiCheckBoxValueChanged, true);
            app.HCgmiCheckBox.Text = 'HC (g/mi)';
            app.COgmiCheckBox = uicheckbox(app.GridLayout52);
            app.COgmiCheckBox.ValueChangedFcn = createCallbackFcn(app, @COgmiCheckBoxValueChanged, true);
            app.COgmiCheckBox.Text = 'CO (g/mi)';
            app.NOxgmiCheckBox = uicheckbox(app.GridLayout52);
            app.NOxgmiCheckBox.ValueChangedFcn = createCallbackFcn(app, @NOxgmiCheckBoxValueChanged, true);
            app.NOxgmiCheckBox.Text = 'NOx (g/mi)';
            app.CO2gkmCheckBox = uicheckbox(app.GridLayout52);
            app.CO2gkmCheckBox.ValueChangedFcn = createCallbackFcn(app, @CO2gkmCheckBoxValueChanged, true);
            app.CO2gkmCheckBox.Text = 'CO2 (g/km)';
            app.USFuelEconomyCheckBox = uicheckbox(app.GridLayout52);
            app.USFuelEconomyCheckBox.ValueChangedFcn = createCallbackFcn(app, @USFuelEconomyCheckBoxValueChanged, true);
            app.USFuelEconomyCheckBox.Text = 'Fuel Economy (US MPGe)';
            app.FuelEconomyL100kmCheckBox = uicheckbox(app.GridLayout52);
            app.FuelEconomyL100kmCheckBox.ValueChangedFcn = createCallbackFcn(app, @FuelEconomyL100kmCheckBoxValueChanged, true);
            app.FuelEconomyL100kmCheckBox.Text = 'Fuel Economy (L/100km)';
            app.GridLayout53 = uigridlayout(app.GridLayout18);
            app.ClearAllButton = uibutton(app.GridLayout53, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Icon = fullfile(pathToMLAPP, 'pics', 'delete.svg');
            app.ClearAllButton.Tooltip = {'Unselect all the output plots'};
            app.ClearAllButton.Text = 'Clear All';
            app.GridLayout57 = uigridlayout(app.GridLayout18);
            app.GridLayout58 = uigridlayout(app.GridLayout18);
            app.VisualizationDashboardPanel = uipanel(app.GridLayout51);
            app.VisualizationDashboardPanel.Title = '   Visualization Dashboard';
            app.GridLayout59 = uigridlayout(app.VisualizationDashboardPanel);
            app.Panel = uipanel(app.GridLayout59);
            app.PastResultsLabel = uilabel(app.GridLayout51);
            app.PastResultsLabel.Text = '';
            app.SessionLogTab = uitab(app.TabGroup);
            app.SessionLogTab.Title = 'Session Log';
            app.GridLayout54 = uigridlayout(app.SessionLogTab);
            app.SessiondetailsPanel = uipanel(app.GridLayout54);
            app.SessiondetailsPanel.Title = 'Session details';
            app.GridLayout43 = uigridlayout(app.SessiondetailsPanel);
            app.TimeLogLabel = uilabel(app.GridLayout43);
            app.TimeLogLabel.Text = 'Label3';
            app.SessionhistoryPanel = uipanel(app.GridLayout54);
            app.SessionhistoryPanel.Title = 'Session history';
            app.GridLayout42 = uigridlayout(app.SessionhistoryPanel);
            app.LogTextArea = uitextarea(app.GridLayout42);
            app.LogTextArea.Editable = 'off';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GridLayout55 = uigridlayout(app.ReportTab);
            app.ReportAxes = uiaxes(app.GridLayout55);
            xlabel(app.ReportAxes, 'Time (s)')
            zlabel(app.ReportAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEVApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end